classdef AnalizadorSenales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GrficaPanel                 matlab.ui.container.Panel
        SignalsSelector             matlab.ui.control.ListBox
        UIAxes                      matlab.ui.control.UIAxes
        ProcesamientodelaSealPanel  matlab.ui.container.Panel
        ObtencindeParmetrosPanel    matlab.ui.container.Panel
        Tree_2                      matlab.ui.container.CheckBoxTree
        FrecuenciaCardacaNode       matlab.ui.container.TreeNode
        IntervaloRRNode_3           matlab.ui.container.TreeNode
        AmplitudNode_3              matlab.ui.container.TreeNode
        GuardarButton               matlab.ui.control.Button
        CalcularButton              matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        TipodeSealButtonGroup       matlab.ui.container.ButtonGroup
        EMGButton                   matlab.ui.control.RadioButton
        EEGButton                   matlab.ui.control.RadioButton
        ECGButton                   matlab.ui.control.RadioButton
        ImportarButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
        function y = transform(app, x)
            y = zeros(1, height(x)*5000);
            for k = 1:height(x)
                values = x{k,1};
                y(1,5000*(k-1)+1:5000*k) = values{1};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportarButton
        function importar(app, event)
           
            [file, location] = uigetfile({'*.mat';'*.dat';'*.edf'}); % Elegir archivo EDF

            app.Data = edfread([location, file]); % Importar datos del archivo EDF

            names = app.Data.Properties.VariableNames; % Los nombres de los canales de mi señal

            app.SignalsSelector.Items = names(2:end);

            reference = app.Data(:,names(1));

            selected_data = app.Data(:,app.SignalsSelector.Value);

            processed_data = transform(app, selected_data);

            reference_data = transform(app, reference);

            sampling_frequency = length(selected_data{1,1}{1})/seconds(selected_data.Properties.TimeStep);

            time = seconds(selected_data.Properties.StartTime): 1/sampling_frequency : seconds(selected_data.Properties.TimeStep*height(selected_data))-1/sampling_frequency;
            
            plot(app.UIAxes, time, processed_data-reference_data)
            title(app.UIAxes, "Registro ECG - "+app.SignalsSelector.Value)
            xlabel(app.UIAxes, "Tiempo [s]")
            ylabel(app.UIAxes, "Voltaje [uV]")
        end

        % Value changed function: SignalsSelector
        function cambiarCanal(app, event)
            value = app.SignalsSelector.Value;
            
            selected_data = app.Data(:, value);

            processed_data = transform(app, selected_data);

            sampling_frequency = length(selected_data{1,1}{1})/seconds(selected_data.Properties.TimeStep);

            time = seconds(selected_data.Properties.StartTime): 1/sampling_frequency : seconds(selected_data.Properties.TimeStep*height(selected_data))-1/sampling_frequency;
            
            plot(app.UIAxes, time, processed_data)
            title(app.UIAxes, "Registro ECG - Abdomen - 1")
            xlabel(app.UIAxes, "Tiempo [s]")
            ylabel(app.UIAxes, "Voltaje [mV]")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportarButton = uibutton(app.UIFigure, 'push');
            app.ImportarButton.ButtonPushedFcn = createCallbackFcn(app, @importar, true);
            app.ImportarButton.Text = 'Importar';
            app.TipodeSealButtonGroup = uibuttongroup(app.UIFigure);
            app.TipodeSealButtonGroup.Title = 'Tipo de Señal';
            app.ECGButton = uiradiobutton(app.TipodeSealButtonGroup);
            app.ECGButton.Text = 'ECG';
            app.ECGButton.Value = true;
            app.EEGButton = uiradiobutton(app.TipodeSealButtonGroup);
            app.EEGButton.Text = 'EEG';
            app.EMGButton = uiradiobutton(app.TipodeSealButtonGroup);
            app.EMGButton.Text = 'EMG';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Parámetro'; 'Valor'};
            app.UITable.RowName = {};
            app.ObtencindeParmetrosPanel = uipanel(app.UIFigure);
            app.ObtencindeParmetrosPanel.Title = 'Obtención de Parámetros';
            app.CalcularButton = uibutton(app.ObtencindeParmetrosPanel, 'push');
            app.CalcularButton.Text = 'Calcular';
            app.GuardarButton = uibutton(app.ObtencindeParmetrosPanel, 'push');
            app.GuardarButton.Text = 'Guardar';
            app.Tree_2 = uitree(app.ObtencindeParmetrosPanel, 'checkbox');
            app.FrecuenciaCardacaNode = uitreenode(app.Tree_2);
            app.FrecuenciaCardacaNode.Text = 'Frecuencia Cardíaca';
            app.IntervaloRRNode_3 = uitreenode(app.Tree_2);
            app.IntervaloRRNode_3.Text = 'Intervalo R-R';
            app.AmplitudNode_3 = uitreenode(app.Tree_2);
            app.AmplitudNode_3.Text = 'Amplitud';
            app.ProcesamientodelaSealPanel = uipanel(app.UIFigure);
            app.ProcesamientodelaSealPanel.Title = 'Procesamiento de la Señal';
            app.GrficaPanel = uipanel(app.UIFigure);
            app.GrficaPanel.Title = 'Gráfica';
            app.UIAxes = uiaxes(app.GrficaPanel);
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Voltaje [mV]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [-1 1];
            app.SignalsSelector = uilistbox(app.GrficaPanel);
            app.SignalsSelector.Items = {'Electrodo 1'};
            app.SignalsSelector.ValueChangedFcn = createCallbackFcn(app, @cambiarCanal, true);
            app.SignalsSelector.Value = 'Electrodo 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalizadorSenales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end