classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ModoATab                    matlab.ui.container.Tab
        LabelRecall                 matlab.ui.control.Label
        LabelF1                     matlab.ui.control.Label
        EtiquetaspredichasLabel     matlab.ui.control.Label
        UITable_Predichas           matlab.ui.control.Table
        ParteALabel                 matlab.ui.control.Label
        TabladecaractersticasLabel  matlab.ui.control.Label
        EtiquetasrealesLabel        matlab.ui.control.Label
        LabelPrecision              matlab.ui.control.Label
        LabelAccuracy               matlab.ui.control.Label
        CargarModeloButton          matlab.ui.control.Button
        UITable_Reales              matlab.ui.control.Table
        UITable_Caracteristicas     matlab.ui.control.Table
        ModoBTab                    matlab.ui.container.Tab
        conexionButton              matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public) 
        TablaMaestra;  % Aquí almacenas tu variable 'table'
        X_Data;        % (Otras variables que necesites)
        Y_Labels;
        dispositivoBLE;
        caracteristicaDatos;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarModeloButton
        function CargarModeloButtonPushed(app, event)
            try
                % Seleccionar archivo
                [nombre_archivo, ruta_archivo] = uigetfile('*.mat', ...
                    'Selecciona el archivo del modelo (.mat)');
                if isequal(nombre_archivo, 0)
                    uialert(app.UIFigure, 'No se seleccionó ningún archivo.', 'Aviso');
                    return;
                end
        
                rutaCompleta = fullfile(ruta_archivo, nombre_archivo);
                datos = load(rutaCompleta);
                vars = fieldnames(datos);
        
                % --- Buscar variables disponibles ---
                % Buscar Y_test
                varY = vars(contains(vars, 'Y_test', 'IgnoreCase', true));
                varPred = vars(contains(vars, 'pred_test', 'IgnoreCase', true));
        
                if isempty(varY) || isempty(varPred)
                    uialert(app.UIFigure, 'El archivo no contiene Y_test o pred_test.', 'Error');
                    return;
                end
        
                Y_test = datos.(varY{1});
                pred_test = datos.(varPred{1});
         %% 🟢 TABLA DE CARACTERÍSTICAS (desde archivo CSV externo)
                try
                    % Ruta fija del archivo CSV (ajusta si está en otra carpeta)
                    rutaCSV = fullfile(pwd, 'YEAH_GLOBAL_FINAL_SUBIR_BAJAR_ESTANDARIZADO_FILTRADO_CORR_BASE.csv');
                
                    if ~isfile(rutaCSV)
                        uialert(app.UIFigure, sprintf('No se encontró el archivo:\n%s', rutaCSV), 'Archivo no encontrado');
                    else
                        % Leer CSV con las características
                        dataCSV = readtable(rutaCSV, 'Delimiter', ',', 'VariableNamingRule', 'preserve');
                
                        % Verificar cantidad de columnas
                        nCols = width(dataCSV);
                        if nCols > 2
                            % Eliminar las últimas 2 columnas
                            dataCSV = dataCSV(:, 1:nCols-2);
                        end
                
                        % Mostrar primeras 20 filas (para no sobrecargar la GUI)
                        maxFilas = min(20, height(dataCSV));
                        app.UITable_Caracteristicas.Data = dataCSV(1:maxFilas, :);
                
                        % Mostrar encabezados reales del CSV
                        app.UITable_Caracteristicas.ColumnName = dataCSV.Properties.VariableNames;
                
                        fprintf('✓ Características cargadas desde: %s\n', rutaCSV);
                        fprintf('  (%d filas, %d columnas mostradas)\n', height(dataCSV), width(dataCSV));
                    end
                catch ME
                    uialert(app.UIFigure, sprintf('Error al leer el CSV de características:\n%s', ME.message), 'Error CSV');
                end
                % === TABLAS DE ETIQUETAS ===
                app.UITable_Reales.Data = table(string(Y_test(:)), 'VariableNames', {'Etiqueta_Real'});
                app.UITable_Predichas.Data = table(string(pred_test(:)), 'VariableNames', {'Etiqueta_Predicha'});
                app.UITable_Reales.ColumnName = {'Etiqueta real'};
                app.UITable_Predichas.ColumnName = {'Etiqueta predicha'};
                %% 🟣 CÁLCULO DE MÉTRICAS (versión robusta)
                if numel(Y_test) ~= numel(pred_test)
                    error('Las etiquetas reales y predichas no tienen la misma longitud.');
                end
        
                % Convertir todo a texto primero para garantizar compatibilidad
                Y_test_str = string(Y_test);
                pred_test_str = string(pred_test);
        
                % Luego a categórico
                Y_test_cat = categorical(Y_test_str);
                pred_test_cat = categorical(pred_test_str);
        
                % Asegurar mismas categorías
                allCats = union(categories(Y_test_cat), categories(pred_test_cat));
                Y_test_cat = reordercats(Y_test_cat, allCats);
                pred_test_cat = reordercats(pred_test_cat, allCats);
        
                % Calcular métricas
                clases = categories(Y_test_cat);
                numClases = numel(clases);
                precision_i = zeros(numClases,1);
                recall_i = zeros(numClases,1);
                f1_i = zeros(numClases,1);
        
                for c = 1:numClases
                    clase = clases{c};
                    TP = sum(pred_test_cat == clase & Y_test_cat == clase);
                    FP = sum(pred_test_cat == clase & Y_test_cat ~= clase);
                    FN = sum(pred_test_cat ~= clase & Y_test_cat == clase);
        
                    precision_i(c) = TP / max(TP + FP, 1);
                    recall_i(c) = TP / max(TP + FN, 1);
                    f1_i(c) = 2 * (precision_i(c) * recall_i(c)) / max((precision_i(c) + recall_i(c)), 1);
                end
        
                precision_macro = mean(precision_i);
                recall_macro = mean(recall_i);
                f1_macro = mean(f1_i);
                accuracy = mean(pred_test_cat == Y_test_cat);
        
                % Mostrar métricas en la interfaz
                app.LabelAccuracy.Text  = sprintf('Accuracy: %.2f%%', accuracy * 100);
                app.LabelPrecision.Text = sprintf('Precisión: %.2f%%', precision_macro * 100);
                app.LabelRecall.Text    = sprintf('Recall: %.2f%%', recall_macro * 100);
                app.LabelF1.Text        = sprintf('F1 Score: %.2f%%', f1_macro * 100);
        
                % También mostrar en consola (por si quieres verificar)
                fprintf('\n=== MÉTRICAS DEL MODELO ===\n');
                fprintf('Accuracy: %.2f%%\n', accuracy * 100);
                fprintf('Precisión: %.2f%%\n', precision_macro * 100);
                fprintf('Recall: %.2f%%\n', recall_macro * 100);
                fprintf('F1 Score: %.2f%%\n', f1_macro * 100);
            catch ME
                uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Error');
            end 
        end

        % Callback function
        function ProcesarButtonPushed(app, event)
            
        end

        % Button pushed function: conexionButton
        function conexionButtonPushed(app, event)
           
        % Dirección MAC del dispositivo BLE (ajusta si cambia)
        NOMBRE_O_MAC = "D2:3A:3D:8A:20:19";
    
        try
            % Mostrar estado
            app.conexionButton.Text = "Conectando...";
            drawnow;
    
            % Intentar conexión BLE
            app.dispositivoBLE = ble(NOMBRE_O_MAC);
    
            % Si llega aquí, la conexión fue exitosa
            app.conexionButton.Text = "Conectado";
            uialert(app.UIFigure, ...
                sprintf("Conexión exitosa con: %s", app.dispositivoBLE.Name), ...
                "Estado");
    
        catch ME
            % En caso de error
            app.conexionButton.Text = "Error;
            uialert(app.UIFigure, ...
                sprintf("Error de conexión:\n%s", ME.message), ...
                "Error");
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ModoATab = uitab(app.TabGroup);
            app.ModoATab.Title = 'Modo A';
            app.UITable_Caracteristicas = uitable(app.ModoATab);
            app.UITable_Caracteristicas.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_Caracteristicas.RowName = {};
            app.UITable_Reales = uitable(app.ModoATab);
            app.UITable_Reales.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_Reales.RowName = {};
            app.CargarModeloButton = uibutton(app.ModoATab, 'push');
            app.CargarModeloButton.ButtonPushedFcn = createCallbackFcn(app, @CargarModeloButtonPushed, true);
            app.CargarModeloButton.Text = 'Importar';
            app.LabelAccuracy = uilabel(app.ModoATab);
            app.LabelAccuracy.Text = 'Accuracy: ';
            app.LabelPrecision = uilabel(app.ModoATab);
            app.LabelPrecision.Text = 'Precisión: ';
            app.EtiquetasrealesLabel = uilabel(app.ModoATab);
            app.EtiquetasrealesLabel.Text = {'Etiquetas '; 'reales'};
            app.TabladecaractersticasLabel = uilabel(app.ModoATab);
            app.TabladecaractersticasLabel.Text = 'Tabla de características';
            app.ParteALabel = uilabel(app.ModoATab);
            app.ParteALabel.Text = 'Parte A';
            app.UITable_Predichas = uitable(app.ModoATab);
            app.UITable_Predichas.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_Predichas.RowName = {};
            app.EtiquetaspredichasLabel = uilabel(app.ModoATab);
            app.EtiquetaspredichasLabel.Text = {'Etiquetas '; 'predichas'};
            app.LabelF1 = uilabel(app.ModoATab);
            app.LabelF1.Text = 'F1 Score:';
            app.LabelRecall = uilabel(app.ModoATab);
            app.LabelRecall.Text = 'Recall:';
            app.ModoBTab = uitab(app.TabGroup);
            app.ModoBTab.Title = 'Modo B';
            app.conexionButton = uibutton(app.ModoBTab, 'push');
            app.conexionButton.ButtonPushedFcn = createCallbackFcn(app, @conexionButtonPushed, true);
            app.conexionButton.Text = 'conexion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end